classdef captureStream < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CaptureStreamUIFigure         matlab.ui.Figure
        InputdirectoryEditFieldLabel  matlab.ui.control.Label
        Inputdir                      matlab.ui.control.EditField
        StartButton                   matlab.ui.control.Button
        Mode                          matlab.ui.control.Switch
        Source                        matlab.ui.control.Switch
        SelectDir                     matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputdirectoryEditFieldLabel.Visible='off';
            app.Inputdir.Visible='off';
            app.SelectDir.Visible='off';
        end

        % Value changed function: Inputdir
        function InputdirValueChanged(app, event)
            value = app.Inputdir.Value;
            
        end

        % Callback function
        function SimulationSwitchValueChanged(app, event)
            value = app.SimulationSwitch.Value;
            
            if(strcmpi(value,'simulation'))
                app.Inputdir.Visible='on';
                app.InputdirectoryEditFieldLabel.Visible='on';
            else
                app.Inputdir.Visible='off';
                app.InputdirectoryEditFieldLabel.Visible='off';
            end
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            value = app.Mode.Value;
            
            
            if(strcmpi(value,'simulation'))
                app.Inputdir.Visible='on';
                app.InputdirectoryEditFieldLabel.Visible='on';
                app.SelectDir.Visible='on';
            else
                app.Inputdir.Visible='off';
                app.InputdirectoryEditFieldLabel.Visible='off';
                app.SelectDir.Visible='off';
            end
        end

        % Button pushed function: SelectDir
        function SelectDirPushed(app, event)
            d=uigetdir();
            if(isempty(d))
                return;
            end
            dchk=dirFolders(d);
            if(~any(strcmp(dchk,'MIPI_0')))
                errordlg('no MIPI_0 folder in directory','modal');
                return;
            end
            
            
            app.Inputdir.Value=d;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


            inDir =app.Inputdir.Value;
            if(strcmpi(app.Mode.Value,'Simulation'))
                gpMode = 3;%SplittedFramesSimulation
            else
            gpMode = 2;%SplittedFrames
            end
           if(strcmpi(app.Source.Value,'USB'))
               gpType=0;
           else
               gpType=1;
           end
            f=figure;
            fprintf('Starting GPlab...');
            gplab=GPlab(gpMode,gpType,0,512,inDir,tempdir); %hardware
            
            fprintf('done\n');
            
            clim = [4095 0];
            a=axes('parent',f);
            
            while(ishandle(f))
                fprintf('get...');
                gplab.start();
                data=gplab.getFragment(0);
                gplab.stop();
                fprintf('parse...');
                ivs=io.FG.readFrames(data,1);
                fprintf('image...');
                im = flipud(Utils.raw2img(ivs,0,[512 512]));
                fprintf('display...');
                clim(1)=min(clim(1),min(im(:)));
                clim(2)=max(clim(2),max(im(:)));
                imagesc(im,'parent',a);
                set(a,'CLim',clim);
                drawnow;
                fprintf('done\n');
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CaptureStreamUIFigure = uifigure;
            app.CaptureStreamUIFigure.Name = 'CaptureStream';
            app.InputdirectoryEditFieldLabel = uilabel(app.CaptureStreamUIFigure);
            app.InputdirectoryEditFieldLabel.Text = 'Input directory';
            app.Inputdir = uieditfield(app.CaptureStreamUIFigure, 'text');
            app.Inputdir.ValueChangedFcn = createCallbackFcn(app, @InputdirValueChanged, true);
            app.StartButton = uibutton(app.CaptureStreamUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Mode = uiswitch(app.CaptureStreamUIFigure, 'slider');
            app.Mode.Items = {'Simulation', 'Hardware'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Hardware';
            app.Source = uiswitch(app.CaptureStreamUIFigure, 'slider');
            app.Source.Items = {'USB', 'PCIexpress'};
            app.Source.Value = 'USB';
            app.SelectDir = uibutton(app.CaptureStreamUIFigure, 'push');
            app.SelectDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirPushed, true);
            app.SelectDir.Text = '...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = captureStream

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CaptureStreamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CaptureStreamUIFigure)
        end
    end
end